classdef randomStudentGUI_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HelpMenu                   matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        READMEMenu                 matlab.ui.container.Menu
        callStudentButton          matlab.ui.control.Button
        classStatisticsPanel       matlab.ui.container.Panel
        classCallsLabel            matlab.ui.control.Label
        classCorrectLabel          matlab.ui.control.Label
        classPercentageLabel       matlab.ui.control.Label
        classCalls                 matlab.ui.control.Label
        classCorrect               matlab.ui.control.Label
        classPercentage            matlab.ui.control.Label
        CurrentClassLabel          matlab.ui.control.Label
        CurrentClass               matlab.ui.control.Label
        DrBsRandomStudentGUILabel  matlab.ui.control.Label
        loadClassButton            matlab.ui.control.Button
        CorrectButton              matlab.ui.control.Button
        IncorrectButton            matlab.ui.control.Button
        SkipButton                 matlab.ui.control.Button
        individualStatisticsPanel  matlab.ui.container.Panel
        studentNameLabel           matlab.ui.control.Label
        numCallsLabel              matlab.ui.control.Label
        numCorrectLabel            matlab.ui.control.Label
        percentageLabel            matlab.ui.control.Label
        studentName                matlab.ui.control.Label
        numCalls                   matlab.ui.control.Label
        numCorrect                 matlab.ui.control.Label
        percentage                 matlab.ui.control.Label
    end


    properties (Access = public)
        courseInfo; % all data is stored in this structure
        filename; % to store the name of the file for loading/saving purposes
        randStudent; % to keep track of who is called
    end

    methods (Access = public)
    
        % function that updates the statistics in the GUI  
        function updateStats(app,n)
            % use nargin to allow for updating class stats on first load (call function without 'n')
            if nargin == 2
                % individual stats
                app.studentName.Text = app.courseInfo(n).names;
                
                nCorrect = app.courseInfo(n).numCorrect;
                app.numCorrect.Text = num2str(nCorrect);
                
                nCalls = app.courseInfo(n).calls;
                app.numCalls.Text = num2str(nCalls);
                
                % to avoid divide by 0 when calculating percentage
                if nCalls == 0
                    app.percentage.Text = 'N/A';
                else
                    percent = nCorrect / nCalls * 100;
                    app.percentage.Text = [num2str(percent) '%'];
                end
            end
                
            % class statistics
            totalCalls = 0;
            totalCorrect = 0;
            for i = 1:length(app.courseInfo)
                totalCalls = totalCalls + app.courseInfo(i).calls;
                totalCorrect = totalCorrect + app.courseInfo(i).numCorrect;
            end
            app.classCalls.Text = num2str(totalCalls);
            app.classCorrect.Text = num2str(totalCorrect);
            app.classPercentage.Text = [num2str(totalCorrect/totalCalls*100) '%'];
        end
        
        % helper function that smartly handles button visibility
        function callStudentButtonHelper(app)
            app.callStudentButton.Enable = 'off';
            app.loadClassButton.Enable = 'off';
            app.CorrectButton.Enable = 'on';
            app.IncorrectButton.Enable = 'on';
            app.SkipButton.Enable = 'on';
        end
        
        function answerButtonHelper(app)            
            app.callStudentButton.Enable = 'on';
            app.loadClassButton.Enable = 'on';
            app.CorrectButton.Enable = 'off';
            app.IncorrectButton.Enable = 'off';
            app.SkipButton.Enable = 'off';          
        end
    
        function saveData(app)
            courseInfo = app.courseInfo;
            file = app.filename;
            save(file,'courseInfo');
        end
        
    end


    methods (Access = private)

        % Button pushed function: loadClassButton
        function loadClassButtonPushed(app, event)
            % !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!! %
            file = uigetfile('*.mat'); % FILE MUST CONTAIN STRUCTURE
            app.filename = file;
            load(file);
            app.courseInfo = courseInfo; % STRUCTURE MUST BE NAMED 'courseInfo'
            % courseInfo must have fields = {courseName, names, calls, numCorrect, probability};
            % !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!! %
            
            app.CurrentClass.Text = app.courseInfo(1).courseName;
            app.callStudentButton.Enable = 'on';
            updateStats(app);
        end

        % Button pushed function: callStudentButton
        function callStudentButtonPushed(app, event)
            % enable / disable buttons as now appropriate
            callStudentButtonHelper(app);
            numStudents = length(app.courseInfo);
            population = 1:numStudents;
            probability = zeros(1,numStudents);
            for i = 1:length(app.courseInfo)
                probability(i) = app.courseInfo(i).probability;
            end
            
            randomStudent = randsample(population,1,true,probability);
            app.randStudent = randomStudent;
            
            updateStats(app,randomStudent);
            app.courseInfo(randomStudent).calls = app.courseInfo(randomStudent).calls + 1;
            
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            answerButtonHelper(app);
            app.courseInfo(app.randStudent).numCorrect = app.courseInfo(app.randStudent).numCorrect + 1;
            app.courseInfo(app.randStudent).probability = app.courseInfo(app.randStudent).probability * 0.5; % 50% less likely
            saveData(app);
            updateStats(app,app.randStudent);
        end

        % Button pushed function: IncorrectButton
        function IncorrectButtonPushed(app, event)
            answerButtonHelper(app);
            app.courseInfo(app.randStudent).probability = app.courseInfo(app.randStudent).probability * 1.1; % 10% more likely
            saveData(app);
            updateStats(app,app.randStudent);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            answerButtonHelper(app);
            app.courseInfo(app.randStudent).calls = app.courseInfo(app.randStudent).calls - 1; % was already added when called, so need to remedy
            saveData(app);
            updateStats(app,app.randStudent);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.READMEMenu = uimenu(app.HelpMenu);
            app.READMEMenu.Accelerator = 'R';
            app.READMEMenu.Text = 'README';
            app.callStudentButton = uibutton(app.UIFigure, 'push');
            app.callStudentButton.ButtonPushedFcn = createCallbackFcn(app, @callStudentButtonPushed, true);
            app.callStudentButton.Enable = 'off';
            app.callStudentButton.Text = 'callStudent';
            app.classStatisticsPanel = uipanel(app.UIFigure);
            app.classStatisticsPanel.Title = 'classStatistics';
            app.classCallsLabel = uilabel(app.classStatisticsPanel);
            app.classCallsLabel.Text = 'classCalls:';
            app.classCorrectLabel = uilabel(app.classStatisticsPanel);
            app.classCorrectLabel.Text = 'classCorrect:';
            app.classPercentageLabel = uilabel(app.classStatisticsPanel);
            app.classPercentageLabel.Text = 'classPercentage:';
            app.classCalls = uilabel(app.classStatisticsPanel);
            app.classCalls.Text = '';
            app.classCorrect = uilabel(app.classStatisticsPanel);
            app.classCorrect.Text = '';
            app.classPercentage = uilabel(app.classStatisticsPanel);
            app.classPercentage.Text = '';
            app.CurrentClassLabel = uilabel(app.classStatisticsPanel);
            app.CurrentClassLabel.Text = 'Current Class:';
            app.CurrentClass = uilabel(app.classStatisticsPanel);
            app.CurrentClass.Text = 'loadClass to begin';
            app.DrBsRandomStudentGUILabel = uilabel(app.UIFigure);
            app.DrBsRandomStudentGUILabel.Text = 'Dr.B''s Random Student GUI';
            app.loadClassButton = uibutton(app.UIFigure, 'push');
            app.loadClassButton.ButtonPushedFcn = createCallbackFcn(app, @loadClassButtonPushed, true);
            app.loadClassButton.Text = 'loadClass';
            app.CorrectButton = uibutton(app.UIFigure, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Enable = 'off';
            app.CorrectButton.Text = 'Correct';
            app.IncorrectButton = uibutton(app.UIFigure, 'push');
            app.IncorrectButton.ButtonPushedFcn = createCallbackFcn(app, @IncorrectButtonPushed, true);
            app.IncorrectButton.Enable = 'off';
            app.IncorrectButton.Text = 'Incorrect';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Enable = 'off';
            app.SkipButton.Text = 'Skip';
            app.individualStatisticsPanel = uipanel(app.UIFigure);
            app.individualStatisticsPanel.Title = 'individualStatistics';
            app.studentNameLabel = uilabel(app.individualStatisticsPanel);
            app.studentNameLabel.Text = 'studentName:';
            app.numCallsLabel = uilabel(app.individualStatisticsPanel);
            app.numCallsLabel.Text = 'numCalls:';
            app.numCorrectLabel = uilabel(app.individualStatisticsPanel);
            app.numCorrectLabel.Text = 'numCorrect:';
            app.percentageLabel = uilabel(app.individualStatisticsPanel);
            app.percentageLabel.Text = 'percentage:';
            app.studentName = uilabel(app.individualStatisticsPanel);
            app.studentName.Text = '';
            app.numCalls = uilabel(app.individualStatisticsPanel);
            app.numCalls.Text = '';
            app.numCorrect = uilabel(app.individualStatisticsPanel);
            app.numCorrect.Text = '';
            app.percentage = uilabel(app.individualStatisticsPanel);
            app.percentage.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = randomStudentGUI_beta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end